classdef Copy_of_swj_twochannels_Beautiful_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        Switch            matlab.ui.control.Switch
        Lamp              matlab.ui.control.Lamp
        Button            matlab.ui.control.Button
        COMBoxLabel       matlab.ui.control.Label
        ListBox           matlab.ui.control.ListBox
        Button_Danyin     matlab.ui.control.Button
        Lamp_QPSK         matlab.ui.control.Lamp
        Button_PocDanyin  matlab.ui.control.Button
        Lamp_PocDanyin    matlab.ui.control.Lamp
        Button_PocQPSK    matlab.ui.control.Button
        Lamp_PocQPSK      matlab.ui.control.Lamp
        Label_PocQPSK     matlab.ui.control.Label
        Label_road2       matlab.ui.control.Label
        Label_Ac2         matlab.ui.control.Label
        Label_Pc2         matlab.ui.control.Label
        Label_QPSK        matlab.ui.control.Label
        Label_Ac2_2       matlab.ui.control.Label
        Label_Pc2_2       matlab.ui.control.Label
        dBLabel           matlab.ui.control.Label
        dBLabel_2         matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
        Image             matlab.ui.control.Image
        Label_9           matlab.ui.control.Label
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes1           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        COM_x % 记录可用串口
        setupCOM %所建立的串口
        
          
        %% 通道设置
            f %单音信号的频率
            N %通道数
            AN0 %AN0即为不同通道atten的值，范围0-1，通道一为1
            AN %转化为12bit整数
            pN0 %pN0即为不同通道phase的值，范围0-1，通道一为0
            pN  
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %初始界面设置
            app.COM_x = serialportlist("available");%搜索可用串口
            if isempty(app.COM_x)
                warndlg('No available COM port', 'Warning');
            else
                app.ListBox.Items = app.COM_x;
            end 

            %% 通道设置
            app.f=1.92e6; %单音信号的频率
            app.N=2;  %通道数
            app.AN0=[0.8 0.6 ]; %AN0即为不同通道atten的值，范围0.56-1，通道一为1
            app.AN=round(app.AN0*(2^11-1)); %转化为12bit整数
            app.pN0=[0.1 0.49]; %pN0即为不同通道phase的值，范围0-1，通道一为0
            app.pN=app.pN0*2*pi;

          
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = str2double(app.Switch.Value);
            if (value==1)
                app.setupCOM=serialport(app.COM_x,115200,'Timeout',100000);   % 串口参数配置
                app.Lamp.Color='g';
            else
                delete(app.setupCOM);
                app.Lamp.Color='r';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.COM_x = serialportlist("available");%搜索可用串口
            if isempty(app.COM_x)
                warndlg('No available COM port', 'Warning');
            else
                app.ListBox.Items = app.COM_x;
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Button pushed function: Button_Danyin
        function Button_DanyinPushed(app, event)
          Np=256;%常规FFT采样数目
            t=-Np+1:Np-1;%apFFT需要采样的点数为2N-1
            fs=30.72e6;
            AA=[];
            AA1=[];
            AA2=[];
            pace=2;
            
            y_danyin_c_I=[];
            for i=1:app.N
                p0=app.pN(i);
                A0=app.AN(i);
                y_danyin_I=round(A0*cos(2*pi*t*app.f/fs+p0));%采样整数量化
                y_danyin_c_I=[y_danyin_c_I,y_danyin_I,0];
            end
            assignin("base","y_danyin_c_I",y_danyin_c_I);
            %数据处理和传输
            y1_I(y_danyin_c_I>=0)=y_danyin_c_I(y_danyin_c_I>=0);
            y1_I(y_danyin_c_I<0)=y_danyin_c_I(y_danyin_c_I<0)+2^12;
            y_hex_I=dec2hex(y1_I,3);
            y_hex_r_I=reshape(y_hex_I',1,[]);

            for i=1:pace:length(y_hex_r_I)
                ym_I=hex2dec(y_hex_r_I(i:i+1));
                AA1=[AA1,ym_I];
            end
              assignin("base","AA1",AA1);
            
            y_danyin_c_Q=[];
            for i=1:app.N
                p0=app.pN(i);
                A0=app.AN(i);
                y_danyin_Q=round(A0*sin(2*pi*t*app.f/fs+p0));%采样整数量化
                y_danyin_c_Q=[y_danyin_c_Q,y_danyin_Q,0];
            end
            %数据处理和传输
            y1_Q(y_danyin_c_Q>=0)=y_danyin_c_Q(y_danyin_c_Q>=0);
            y1_Q(y_danyin_c_Q<0)=y_danyin_c_Q(y_danyin_c_Q<0)+2^12;
            y_hex_Q=dec2hex(y1_Q,3);
            y_hex_r_Q=reshape(y_hex_Q',1,[]);

            for i=1:pace:length(y_hex_r_Q)
                ym_Q=hex2dec(y_hex_r_Q(i:i+1));
                AA2=[AA2,ym_Q];
            end   
            assignin("base","AA2",AA2);
            AA=[AA1,AA2];
            assignin("base","AA",AA);

            write(app.setupCOM,AA,"uint8");
            data = read(app.setupCOM,1,'uint8');%读取通道数据
            if data==1
                %QPSK信号的产生
                data_length = 1e4;  %基带数据总长度
                fs = 30.72e6;       %采样率30.72MHz （9361最大采样率为61.44MHz）
                Rs = 7.68e6;        %符号速率7.68MHz
                OSR = fs/Rs;        %过采样率（整数，一般不小于4）
                alpha = 0.35;        %根升余弦成型系数 取值一般0.3~1之间  信号双边带带宽B=Rs*（1+alpha）
                M=[];
                NI=[];
                NQ=[];
               for i=1:app.N
                    atten = app.AN0(i);         %幅度衰减，默认atten = 1为数字的最大输出功率
                    phase_m =app.pN0(i);       %取值0~1 随机相位

                    rand('seed',70);
                    trans_data_bit = randi([0 1],1,data_length);                %产生随机数据流
                    trans_data_bio = 1-2*trans_data_bit;                        %基带数据两极化
                    trans_data_IQ_matrix = reshape(trans_data_bio,2,[]);        %qpsk串并转换
                    trans_data_I = trans_data_IQ_matrix(1,:);
                    trans_data_Q = trans_data_IQ_matrix(2,:);


                    rcosfir = rcosdesign(alpha,6,OSR,'sqrt');                       %根升余弦成型滤波器例化
                    trans_data_upsample_I = upsample(trans_data_I,OSR,0);                  %I路基带上采样
                    trans_data_forming_I = conv(trans_data_upsample_I,rcosfir,'same');     %I路成型
                    trans_data_upsample_Q = upsample(trans_data_Q,OSR,0);                  %Q路基带上采样
                    trans_data_forming_Q = conv(trans_data_upsample_Q,rcosfir,'same');     %Q路成型
                    trans_data_complex = trans_data_forming_I + 1i*trans_data_forming_Q;   %复基带数据生成
                    trans_data_complex = trans_data_complex .* exp(1i*2*pi*phase_m);         %添加随机相位

                    trans_data_max=max(abs(trans_data_complex)); %这就是上次出问题的地方
                    trans_data_forming_I_12bit = round(atten*round(real(trans_data_complex)/trans_data_max * (2^11-1)));  %归一化添加幅度变量
                    trans_data_forming_Q_12bit = round(atten*round(imag(trans_data_complex)/trans_data_max * (2^11-1)));  %归一化添加幅度变量
                    I=trans_data_forming_I_12bit(1:10000);
                    Q=trans_data_forming_Q_12bit(1:10000);

                    
                    NI(i,:)=I;
                    NQ(i,:)=Q;
                    I_z(I>=0)=I(I>=0);
                    I_z(I<0)=I(I<0)+2^12;
                    Q_z(Q>=0)=Q(Q>=0);
                    Q_z(Q<0)=Q(Q<0)+2^12;
                    M=[M,I_z,Q_z];
               end


                M_hex=dec2hex(M,3);
                M_hex_r=reshape(M_hex',1,[]);

                %指标检测
                road1=NI(1,:)+1i* NQ(1,:);%通道1
                road2=NI(2,:)+1i* NQ(2,:);%通道2

                %原信号同步幅度误差
                ACC1=mean(abs(road1));
                ACC2=mean(abs(road2)/abs(road1));
                ACC=[20*log(ACC2)]; %单位为dB

                %原信号同步相位误差
                %PCC1=mean(phase(road1));
                %PCC2=mean(phase(road2)-phase(road1));
                PCC=360*[app.pN0(2)]; %单位为度

                app.Label_Ac2_2.Text=num2str(ACC(1),'%1.4f');

                app.Label_Pc2_2.Text=num2str(PCC(1),'%1.4f');

                %为了图像更加直观，只展示前100个点
                tt=1:100;
                plot(app.UIAxes1,tt,NI(1,1:100),tt,NI(2,1:100));
                ax=app.UIAxes1;
                title(ax,'QPSK原信号I路');
                legend(ax,'通道1','通道2');
                xlabel(ax,"时间");
                ylabel(ax,"幅度");


                plot(app.UIAxes2,tt,NQ(1,1:100),tt,NQ(2,1:100));
                ax=app.UIAxes2;
                title(ax,'QPSK原信号Q路');
                legend(ax,'通道1','通道2');
                xlabel(ax,"时间");
                ylabel(ax,"幅度");

                app.Label_QPSK.Text='正在传输数据';

                road11=(NI(1,:)+1i* NQ(1,:)).* exp(1i*2*pi*(-0.1)).*(10/8);%通道1
                
                
                lim=2^11;
                figure(1)
                set(gcf,'unit','normalized','position',[0.1,0.1,0.4,0.25]);%figture位置，最下角，宽高
                set (gca,'position',[0.1,0.1,0.8,0.8] );%axis位置，最下角，宽高
                subplot(1,2,1)
                scatter(real(road11),imag(road11));grid on;
                axis([-lim,lim,-lim,lim])  % 坐标范围限制
                title('通道1QPSK信号星座图');
                subplot(1,2,2)
                scatter(NI(2,:),NQ(2,:));grid on;
                title('通道2QPSK信号星座图');
                axis([-lim,lim,-lim,lim])  % 坐标范围限制

                AA=[];
                pace=2;
                for i=1:pace:length(M_hex_r)
                    Mm=hex2dec(M_hex_r(i:i+1));
                    AA=[AA,Mm];
                end

                write(app.setupCOM,AA,"uint8");
                data = read(app.setupCOM,1,'uint8');%读取通道数据
                if data==2
                    app.Lamp_QPSK.Color='g';
                    app.Label_QPSK.Text='数据传输完成';
                    app.Label_QPSK.FontColor='b';
                else
                    app.Lamp_QPSK.Color='r';
                    app.Label_QPSK.Text='数据传输失败';
                    app.Label_QPSK.FontColor='r';
                end
            end
        end

        % Callback function
        function Button_QPSKPushed(app, event)
            
        end

        % Button pushed function: Button_PocDanyin
        function Button_PocDanyinPushed(app, event)
            AA=[255,255,255];            
            write(app.setupCOM,AA,'uint8');
           data = read(app.setupCOM,1,'uint8');%读取通道数据
            if data==3
                app.Lamp_PocDanyin.Color='g';
            else
                 app.Lamp_PocDanyin.Color='r';
            end
        end

        % Button pushed function: Button_PocQPSK
        function Button_PocQPSKPushed(app, event)
            AA=[0,15,255];            
            write(app.setupCOM,AA,'uint8');
           data = read(app.setupCOM,1,'uint8');%读取通道数据
            if data==4
                app.Label_PocQPSK.Text='正在接收数据';
            else
                app.Label_PocQPSK.Text='未接收到数据';
            end
            %通道1的IQ数据接收
            AA=[0,0,1];
            write(app.setupCOM,AA,'uint8');
            data1 = read(app.setupCOM,7500,"uint32" );  
            data1_hex = dec2hex(data1); 
            M=[];
            for i=1:7500
                M_12=data1_hex(i,7:8);
                M_34=data1_hex(i,5:6);
                M_56=data1_hex(i,3:4);
                M_78=data1_hex(i,1:2);
                M=[M,M_12,M_34,M_56,M_78];
            end
            M_num=[];
            pace=3;
            for i=1:pace:length(M)
                M_num_w=hex2dec(M(i:i+2));
                M_num=[M_num,M_num_w];
            end
            M_num_r(M_num>=2^11)=M_num(M_num>=2^11)-2^12;
            M_num_r(M_num<2^11)=M_num(M_num<2^11);
            data1_r_I=M_num_r(1:10000);
            data1_r_Q=M_num_r(10001:20000);

            %通道2的IQ数据接收
            AA=[0,0,2];
            write(app.setupCOM,AA,'uint8');
            data2 = read(app.setupCOM,7500,"uint32" );  
            data2_hex = dec2hex(data2); 
            M=[];
            for i=1:7500
                M_12=data2_hex(i,7:8);
                M_34=data2_hex(i,5:6);
                M_56=data2_hex(i,3:4);
                M_78=data2_hex(i,1:2);
                M=[M,M_12,M_34,M_56,M_78];
            end
            M_num=[];
            pace=3;
            for i=1:pace:length(M)
                M_num_w=hex2dec(M(i:i+2));
                M_num=[M_num,M_num_w];
            end
            M_num_r(M_num>=2^11)=M_num(M_num>=2^11)-2^12;
            M_num_r(M_num<2^11)=M_num(M_num<2^11);
            data2_r_I=M_num_r(1:10000);
            data2_r_Q=M_num_r(10001:20000);
                   
            
            %为了图像更加直观，只展示前100个点
            tt=1:100;
            plot(app.UIAxes3,tt,data1_r_I(1001:1100),tt,data2_r_I(1001:1100));
            ax=app.UIAxes3;
            title(ax,'QPSK复原信号I路');
            legend(ax,'通道1','通道2')
            xlabel(ax,"时间");
            ylabel(ax,"幅度");

            plot(app.UIAxes4,tt,data1_r_Q(1001:1100),tt,data2_r_Q(1001:1100));
            ax=app.UIAxes4;
            title(ax,'QPSK复原信号Q路');
            legend(ax,'通道1','通道2')
            xlabel(ax,"时间");
            ylabel(ax,"幅度");
            app.Lamp_PocQPSK.Color='g';
            app.Label_PocQPSK.Text='数据接收完成';
            app.Label_PocQPSK.FontColor='b';

            %指标检测
            road1=data1_r_I+1i* data1_r_Q;%通道1
            road2=data2_r_I+1i* data2_r_Q;%通道2


            %同步幅度误差（不大于+-0.5dB）
            ACC1=mean(abs(road1));
            ACC2=mean(abs(road2)/abs(road1));
            ACC=[20*log(ACC2)]; %单位为dB

            %同步相位误差（不大于+-2度）
            PCC1=mean(phase(road1));
            PCC2=mean(phase(road2)-phase(road1));
            PCC=180/pi*[PCC2]; %单位为度

            app.Label_Ac2.Text=num2str(ACC(1),'%1.4f');
            app.Label_Pc2.Text=num2str(PCC(1),'%1.4f');


            lim=2^11;
            figure(2)
            set(gcf,'unit','normalized','position',[0.1,0.1,0.4,0.25]);%figture位置，最下角，宽高
            set (gca,'position',[0.1,0.1,0.8,0.8] );%axis位置，最下角，宽高
            subplot(1,2,1)
            scatter(data1_r_I,data1_r_Q);grid on;
            axis([-lim,lim,-lim,lim])  % 坐标范围限制
            title('通道1QPSK信号星座图');
            subplot(1,2,2)
            scatter(data2_r_I,data2_r_Q);grid on;
            title('通道2QPSK信号星座图');
            axis([-lim,lim,-lim,lim])  % 坐标范围限制

           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'off', 'on'};
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.Lamp = uilamp(app.GridLayout);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '搜索串口';
            app.COMBoxLabel = uilabel(app.GridLayout);
            app.COMBoxLabel.Text = '串口';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {'COM1', 'COM2', 'COM3', 'COM4', '', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'COM1';
            app.Button_Danyin = uibutton(app.GridLayout, 'push');
            app.Button_Danyin.ButtonPushedFcn = createCallbackFcn(app, @Button_DanyinPushed, true);
            app.Button_Danyin.Text = 'B9361初始化';
            app.Lamp_QPSK = uilamp(app.GridLayout);
            app.Button_PocDanyin = uibutton(app.GridLayout, 'push');
            app.Button_PocDanyin.ButtonPushedFcn = createCallbackFcn(app, @Button_PocDanyinPushed, true);
            app.Button_PocDanyin.Text = '单音校准';
            app.Lamp_PocDanyin = uilamp(app.GridLayout);
            app.Button_PocQPSK = uibutton(app.GridLayout, 'push');
            app.Button_PocQPSK.ButtonPushedFcn = createCallbackFcn(app, @Button_PocQPSKPushed, true);
            app.Button_PocQPSK.Text = 'QPSK处理';
            app.Lamp_PocQPSK = uilamp(app.GridLayout);
            app.Label_PocQPSK = uilabel(app.GridLayout);
            app.Label_PocQPSK.Text = '准备接收数据……';
            app.Label_road2 = uilabel(app.GridLayout);
            app.Label_road2.Text = '通道2';
            app.Label_Ac2 = uilabel(app.GridLayout);
            app.Label_Ac2.Text = '0';
            app.Label_Pc2 = uilabel(app.GridLayout);
            app.Label_Pc2.Text = '0';
            app.Label_QPSK = uilabel(app.GridLayout);
            app.Label_QPSK.Text = '准备传输数据……';
            app.Label_Ac2_2 = uilabel(app.GridLayout);
            app.Label_Ac2_2.Text = '0';
            app.Label_Pc2_2 = uilabel(app.GridLayout);
            app.Label_Pc2_2.Text = '0';
            app.dBLabel = uilabel(app.GridLayout);
            app.dBLabel.Text = '原信号同步幅度误差（dB）';
            app.dBLabel_2 = uilabel(app.GridLayout);
            app.dBLabel_2.Text = '校准后同步幅度误差（dB）';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '原信号同步相位误差（°）';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '校准后同步相位误差（°）';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '第八届集成电路创新创业大赛——航天微电子杯';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo.png';
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '搜索串口时请关闭蓝牙';
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.YTick = [0 0.5 1];
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTick = [0 0.5 1];
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_swj_twochannels_Beautiful_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end